classdef GES_Predictor_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UpdateAxesButton              matlab.ui.control.Button
        TurbineModelDropDown          matlab.ui.control.DropDown
        TurbineModelDropDownLabel     matlab.ui.control.Label
        BoatModelDropDown             matlab.ui.control.DropDown
        BoatModelDropDownLabel        matlab.ui.control.Label
        TimeScope_2                   matlab.ui.scope.TimeScope
        TimeScope                     matlab.ui.scope.TimeScope
        TurbineEfficiencyEditField_2  matlab.ui.control.NumericEditField
        TurbineEfficiencyEditFieldLabel_2  matlab.ui.control.Label
        WettedAream2EditField         matlab.ui.control.NumericEditField
        WettedAream2EditFieldLabel    matlab.ui.control.Label
        C_DEditField                  matlab.ui.control.NumericEditField
        C_DEditFieldLabel             matlab.ui.control.Label
        TurbineAream2EditField        matlab.ui.control.NumericEditField
        TurbineAream2EditFieldLabel   matlab.ui.control.Label
        BoatDisplacementkgEditField   matlab.ui.control.NumericEditField
        BoatDisplacementkgEditFieldLabel  matlab.ui.control.Label
        AxialInductionEditField       matlab.ui.control.NumericEditField
        TurbineEfficiencyEditFieldLabel  matlab.ui.control.Label
        WindSpeedmsEditField          matlab.ui.control.NumericEditField
        WindSpeedmsEditFieldLabel     matlab.ui.control.Label
        C_LmaxEditField               matlab.ui.control.NumericEditField
        C_LmaxEditFieldLabel          matlab.ui.control.Label
        SailAream2EditField           matlab.ui.control.NumericEditField
        SailAream2EditFieldLabel      matlab.ui.control.Label
        SimulationControls            simulink.ui.control.SimulationControls
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BoatModelDropDown
        function BoatModelDropDownValueChanged(app, event)
            value = app.BoatModelDropDown.Value;
            if (value == "F-27 Trimaran")
                app.BoatDisplacementkgEditField.Value = 1588;
                app.WettedAream2EditField.Value = 22;
                app.C_DEditField.Value = 0.0035;
                app.C_LmaxEditField.Value = 1;
                app.SailAream2EditField.Value = 50;
            end
        end

        % Value changed function: TurbineModelDropDown
        function TurbineModelDropDownValueChanged(app, event)
            value = app.TurbineModelDropDown.Value;
            if (value == "Watt & Sea")
                app.TurbineEfficiencyEditField_2.Value = 0.5;
                app.TurbineAream2EditField.Value = 0.0453;
            end
        end

        % Button pushed function: UpdateAxesButton
        function UpdateAxesButtonPushed(app, event)
            app.TimeScope
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.SailAream2EditFieldLabel = uilabel(app.UIFigure);
            app.SailAream2EditFieldLabel.Text = 'sail_area';
            app.SailAream2EditField = uieditfield(app.UIFigure, 'numeric');
            app.SailAream2EditField.Value = 50;
            app.C_LmaxEditFieldLabel = uilabel(app.UIFigure);
            app.C_LmaxEditFieldLabel.Text = 'C_L';
            app.C_LmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.C_LmaxEditField.Value = 1;
            app.WindSpeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.WindSpeedmsEditFieldLabel.Text = 'wind_speed';
            app.WindSpeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindSpeedmsEditField.Value = 5.14;
            app.TurbineEfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.TurbineEfficiencyEditFieldLabel.Text = 'zeta';
            app.AxialInductionEditField = uieditfield(app.UIFigure, 'numeric');
            app.AxialInductionEditField.Value = 0.78;
            app.BoatDisplacementkgEditFieldLabel = uilabel(app.UIFigure);
            app.BoatDisplacementkgEditFieldLabel.Text = 'displacement';
            app.BoatDisplacementkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoatDisplacementkgEditField.Value = 1588;
            app.TurbineAream2EditFieldLabel = uilabel(app.UIFigure);
            app.TurbineAream2EditFieldLabel.Text = 'turbine_area';
            app.TurbineAream2EditField = uieditfield(app.UIFigure, 'numeric');
            app.TurbineAream2EditField.Value = 0.04525;
            app.C_DEditFieldLabel = uilabel(app.UIFigure);
            app.C_DEditFieldLabel.Text = 'C_D';
            app.C_DEditField = uieditfield(app.UIFigure, 'numeric');
            app.C_DEditField.Value = 0.00345;
            app.WettedAream2EditFieldLabel = uilabel(app.UIFigure);
            app.WettedAream2EditFieldLabel.Text = 'wetted_area';
            app.WettedAream2EditField = uieditfield(app.UIFigure, 'numeric');
            app.WettedAream2EditField.Value = 22;
            app.TurbineEfficiencyEditFieldLabel_2 = uilabel(app.UIFigure);
            app.TurbineEfficiencyEditFieldLabel_2.Text = 'eta';
            app.TurbineEfficiencyEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TurbineEfficiencyEditField_2.Value = 0.5;
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'Turbine Output'};
            app.TimeScope.Title = 'Turbine Output';
            app.TimeScope.YLabel = 'Power (W)';
            app.TimeScope.XTimeSpan = 60;
            app.TimeScope_2 = uitimescope(app.UIFigure);
            app.TimeScope_2.LegendNames = {'Boat Velocity'};
            app.TimeScope_2.Title = 'Boat Velocity';
            app.TimeScope_2.YLabel = 'V (m/s)';
            app.TimeScope_2.XTimeSpan = 60;
            app.BoatModelDropDownLabel = uilabel(app.UIFigure);
            app.BoatModelDropDownLabel.Text = 'Boat Model';
            app.BoatModelDropDown = uidropdown(app.UIFigure);
            app.BoatModelDropDown.Items = {'Custom', 'F-27 Trimaran', 'Option 2', 'Option 3', 'Option 4'};
            app.BoatModelDropDown.ValueChangedFcn = createCallbackFcn(app, @BoatModelDropDownValueChanged, true);
            app.BoatModelDropDown.Value = 'Custom';
            app.TurbineModelDropDownLabel = uilabel(app.UIFigure);
            app.TurbineModelDropDownLabel.Text = 'Turbine Model';
            app.TurbineModelDropDown = uidropdown(app.UIFigure);
            app.TurbineModelDropDown.Items = {'Custom', 'Watt & Sea', 'Option 2', 'Option 3', 'Option 4'};
            app.TurbineModelDropDown.ValueChangedFcn = createCallbackFcn(app, @TurbineModelDropDownValueChanged, true);
            app.TurbineModelDropDown.Value = 'Custom';
            app.UpdateAxesButton = uibutton(app.UIFigure, 'push');
            app.UpdateAxesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAxesButtonPushed, true);
            app.UpdateAxesButton.Text = 'Update Axes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.SailAream2EditField, 'Value', app.Simulation.TunableVariables, 'sail_area');
            bind(app.WettedAream2EditField, 'Value', app.Simulation.TunableVariables, 'wetted_area');
            bind(app.C_DEditField, 'Value', app.Simulation.TunableVariables, 'C_D');
            bind(app.C_LmaxEditField, 'Value', app.Simulation.TunableVariables, 'C_L');
            bind(app.WindSpeedmsEditField, 'Value', app.Simulation.TunableVariables, 'wind_speed');
            bind(app.AxialInductionEditField, 'Value', app.Simulation.TunableVariables, 'zeta');
            bind(app.TurbineAream2EditField, 'Value', app.Simulation.TunableVariables, 'turbine_area');
            bind(app.TurbineEfficiencyEditField_2, 'Value', app.Simulation.TunableVariables, 'eta');
            bind(app.BoatDisplacementkgEditField, 'Value', app.Simulation.TunableVariables, 'displacement');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Subsystem:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Integrator:1'], app.TimeScope_2);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GES_Predictor_App

            % Associate the Simulink Model
            app.Simulation = simulation('GES_Predictor');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end